classdef RobotControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndProgrammeButton              matlab.ui.control.StateButton
        ResumeProgrammeButton           matlab.ui.control.StateButton
        estopWarning                    matlab.ui.control.Label
        BOOTBOOZEBOTButton              matlab.ui.control.Button
        EStop                           matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        AutonomousModeTab               matlab.ui.container.Tab
        StatusLabel                     matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        EStopPushedLamp_2               matlab.ui.control.Lamp
        EStopPushedLamp_2Label          matlab.ui.control.Label
        CollisionDetectedLamp           matlab.ui.control.Lamp
        CollisionDetectedLampLabel      matlab.ui.control.Label
        AutonomousModeLabel             matlab.ui.control.Label
        EStopPushedLamp                 matlab.ui.control.Lamp
        EStopPushedLampLabel            matlab.ui.control.Label
        SupermoistButton                matlab.ui.control.Button
        BigCreaturesPaleButton          matlab.ui.control.Button
        RooheysButton                   matlab.ui.control.Button
        ServeadeliciousbevvyLabel       matlab.ui.control.Label
        LashesButton                    matlab.ui.control.Button
        UR3ControllerTab                matlab.ui.container.Tab
        ur3ZNeg                         matlab.ui.control.StateButton
        ur3ZPos                         matlab.ui.control.StateButton
        ur3YNeg                         matlab.ui.control.StateButton
        ur3YPos                         matlab.ui.control.StateButton
        ur3XNeg                         matlab.ui.control.StateButton
        ur3XPos                         matlab.ui.control.StateButton
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ur3Joint6                       matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        ur3Joint5                       matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        ur3Joint4                       matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        ur3Joint3                       matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        ur3Joint2                       matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        ur3Joint1                       matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        ur3Speed                        matlab.ui.control.Knob
        EndeffectorstepsizemKnobLabel   matlab.ui.control.Label
        JointmovementLabel              matlab.ui.control.Label
        CartesianmovementGlobalLabel    matlab.ui.control.Label
        UR3ManualControlLabel           matlab.ui.control.Label
        CR5ControllerTab                matlab.ui.container.Tab
        cr5Joint6                       matlab.ui.control.Slider
        Joint6Slider_2Label             matlab.ui.control.Label
        cr5Joint5                       matlab.ui.control.Slider
        Joint5Slider_2Label             matlab.ui.control.Label
        cr5Joint4                       matlab.ui.control.Slider
        Joint4Slider_2Label             matlab.ui.control.Label
        cr5Joint3                       matlab.ui.control.Slider
        Joint3Slider_2Label             matlab.ui.control.Label
        cr5Joint2                       matlab.ui.control.Slider
        Joint2Slider_2Label             matlab.ui.control.Label
        cr5Joint1                       matlab.ui.control.Slider
        Joint1Slider_2Label             matlab.ui.control.Label
        cr5Speed                        matlab.ui.control.Knob
        EndeffectorstepsizemKnobLabel_2  matlab.ui.control.Label
        cr5ZNeg                         matlab.ui.control.StateButton
        cr5ZPos                         matlab.ui.control.StateButton
        cr5YNeg                         matlab.ui.control.StateButton
        cr5YPos                         matlab.ui.control.StateButton
        cr5XNeg                         matlab.ui.control.StateButton
        cr5XPos                         matlab.ui.control.StateButton
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        JointmovementLabel_2            matlab.ui.control.Label
        CartesianmovementGlobalLabel_2  matlab.ui.control.Label
        CR5ManualControlLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        task % the class handle
    end
    
    methods (Access = public)

        function actionEStopStatus(app,flag)


            if app.EStop.Value
                % run the class in e-stop mode
                disp('running in e-stop mode ...');
                
                % wait for the EStop to be released
                while app.EStop.Value == true
                    pause(0.001);
                end

                % hand over control to the manual UI
                app.manualControl;

            end

        end

        function manualControl(app,event)

            while app.ResumeProgrammeButton.Value == false

                stepFlag = 1;
                

                % CHECK THE UR3 CONTROLLER FOR MANUAL INPUTS                
                if app.ur3XPos.Value
                    % POSITIVE X DIRECTION
                    disp('XPos pushed');
                    T = transl(app.ur3Speed.Value,0,0);
                    robot = app.task.UR;
                    app.ur3XPos.Value = false;

                elseif app.ur3XNeg.Value
                    % NEGATIVE X DIRECTION
                    disp('XNeg pushed');
                    T = transl(-app.ur3Speed.Value,0,0);
                    robot = app.task.UR;
                    app.ur3XNeg.Value = false;

                elseif app.ur3YPos.Value
                    % POSITIVE Y DIRECTION
                    disp('YPOS pushed');
                    T = transl(0,app.ur3Speed.Value,0);
                    robot = app.task.UR;
                    app.ur3YPos.Value = false;

                elseif app.ur3YNeg.Value
                    % NEGATIVE Y DIRECTION
                    disp('YNeg pushed');
                    T = transl(0,-app.ur3Speed.Value,0);
                    robot = app.task.UR;
                    app.ur3YNeg.Value = false;   

                elseif app.ur3ZPos.Value
                    % POSITIVE Z DIRECTION
                    disp('ZPOS pushed');
                    T = transl(0,0,app.ur3Speed.Value);
                    robot = app.task.UR;
                    app.ur3ZPos.Value = false;

                elseif app.ur3ZNeg.Value
                    % NEGATIVE Z DIRECTION
                    disp('ZNeg pushed');
                    T = transl(0,0,-app.ur3Speed.Value);
                    robot = app.task.UR;
                    app.ur3ZNeg.Value = false;                     

                % CHECK THE CR5 CONTROLLER FOR MANUAL INPUTS
                elseif app.cr5XPos.Value
                    % POSITIVE X DIRECTION
                    disp('XPos pushed');
                    T = transl(app.cr5Speed.Value,0,0);
                    robot = app.task.CO;
                    app.cr5XPos.Value = false;

                elseif app.cr5XNeg.Value
                    % NEGATIVE X DIRECTION
                    disp('XNeg pushed');
                    T = transl(-app.cr5Speed.Value,0,0);
                    robot = app.task.CO;
                    app.cr5XNeg.Value = false;

                elseif app.cr5YPos.Value
                    % POSITIVE Y DIRECTION
                    disp('YPOS pushed');
                    T = transl(0,app.cr5Speed.Value,0);
                    robot = app.task.CO;
                    app.cr5YPos.Value = false;

                elseif app.cr5YNeg.Value
                    % NEGATIVE Y DIRECTION
                    disp('YNeg pushed');
                    T = transl(0,-app.cr5Speed.Value,0);
                    robot = app.task.CO;
                    app.cr5YNeg.Value = false;   

                elseif app.cr5ZPos.Value
                    % POSITIVE Z DIRECTION
                    disp('ZPOS pushed');
                    T = transl(0,0,app.cr5Speed.Value);
                    robot = app.task.CO;
                    app.cr5ZPos.Value = false;

                elseif app.cr5ZNeg.Value
                    % NEGATIVE Z DIRECTION
                    disp('ZNeg pushed');
                    T = transl(0,0,-app.cr5Speed.Value);
                    robot = app.task.CO;
                    app.cr5ZNeg.Value = false;                     

                else
                    stepFlag = 0;

                end


                if stepFlag == 1
                    app.task.oneStep(robot,T);
                end

                pause(0.001);

            end

        end
    
        function status = getEStopStatus(app,event)

            status = app.EStop.Value;
        end

        function updateJointSliders(app)

            % get the joint values of both robots
            q_ur = app.task.UR.getpos;
            q_cr = app.task.CO.getpos;

            % change the value of the sliders

            % UR3 sliders
            app.ur3Joint1.Value = q_ur(1);
            app.ur3Joint2.Value = q_ur(2);
            app.ur3Joint3.Value = q_ur(3);
            app.ur3Joint4.Value = q_ur(4);
            app.ur3Joint5.Value = q_ur(5);
            app.ur3Joint6.Value = q_ur(6);

            % CR5 sliders
            app.cr5Joint1.Value = q_cr(1);
            app.cr5Joint2.Value = q_cr(2);
            app.cr5Joint3.Value = q_cr(3);
            app.cr5Joint4.Value = q_cr(4);
            app.cr5Joint5.Value = q_cr(5);
            app.cr5Joint6.Value = q_cr(6);            

            
        end 
        
    end
    
    methods (Access = private)
        
        function animateJointAngles(app)

            % store the joint positions in the sliders
            q_cr = [app.cr5Joint1.Value app.cr5Joint2.Value app.cr5Joint3.Value app.cr5Joint4.Value app.cr5Joint5.Value app.cr5Joint6.Value];
            q_ur = [app.ur3Joint1.Value app.ur3Joint2.Value app.ur3Joint3.Value app.ur3Joint4.Value app.ur3Joint5.Value app.ur3Joint6.Value];

            % animate the robot arms and grippers

            % animate the ur3
            app.task.UR.animate(q_ur);
            urEndEffector = app.task.UR.fkine(q_ur).T;
            animateGripper(app.task.URgrip,app.task.URpos,urEndEffector)

            % animate the dobot
            crEndEffector = app.task.CO.fkine(q_cr).T;
            app.task.CO.animate(q_cr);
            animateGripper(app.task.COgrip,app.task.COpos,crEndEffector);

            
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EStop
        function EStopValueChanged(app, event)
            if app.EStop.Value
                app.EStop.Text = '';
                app.estopWarning.Visible = "on";
                app.estopWarning.Enable = "on";
                app.ResumeProgrammeButton.Enable = "off";
                app.ResumeProgrammeButton.Value = false;
                app.ResumeProgrammeButton.Text = "Release E-Stop to resume programme";
                app.EndProgrammeButton.Enable = "off";
                app.EndProgrammeButton.Value = false;
                app.EndProgrammeButton.Text = "Release E-Stop to end programme";
                
            else
                app.EStop.Text = '';
                app.estopWarning.Visible = "off";
                app.estopWarning.Enable = "off";
                app.ResumeProgrammeButton.Enable = "on"
                app.ResumeProgrammeButton.Text = "Resume Programme"
                app.EndProgrammeButton.Enable = "on";
                app.EndProgrammeButton.Text = "End Programme";                
            end
        end

        % Button pushed function: LashesButton
        function LashesButtonPushed(app, event)
            app.task.chooseBeer(1);
            app.task.serveBeer;
        end

        % Button pushed function: BOOTBOOZEBOTButton
        function BOOTBOOZEBOTButtonPushed(app, event)
            app.task = a3(app);

            app.cr5Joint6.Limits = app.task.CO.qlim(6,:);
            app.cr5Joint5.Limits = app.task.CO.qlim(5,:);
            app.cr5Joint4.Limits = app.task.CO.qlim(4,:);
            app.cr5Joint3.Limits = app.task.CO.qlim(3,:);
            app.cr5Joint2.Limits = app.task.CO.qlim(2,:);
            app.cr5Joint1.Limits = app.task.CO.qlim(1,:);

            app.ur3Joint6.Limits = app.task.UR.qlim(6,:);
            app.ur3Joint5.Limits = app.task.UR.qlim(5,:);
            app.ur3Joint4.Limits = app.task.UR.qlim(4,:);
            app.ur3Joint3.Limits = app.task.UR.qlim(3,:);
            app.ur3Joint2.Limits = app.task.UR.qlim(2,:);
            app.ur3Joint1.Limits = app.task.UR.qlim(1,:);
        end

        % Value changed function: ResumeProgrammeButton
        function ResumeProgrammeButtonValueChanged(app, event)
            % value = app.ResumeProgrammeButton.Value;

            if app.EStop == false
                app.ResumeProgrammeButton.Enable = "off";
            end
            
            
        end

        % Value changed function: EndProgrammeButton
        function EndProgrammeButtonValueChanged(app, event)
            value = app.EndProgrammeButton.Value;
            
            if app.EStop == false
                app.EndProgrammeButton.enable = "off";
            end

            delete(app.task);
        end

        % Value changed function: ur3XPos
        function UPur3XPosPushed(app, event)
            value = app.ur3XPos.Value;

        end

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.ur3XPos.Value;
            

        end

        % Value changing function: cr5Joint1, cr5Joint2, cr5Joint3, 
        % ...and 9 other components
        function cr5Joint6ValueChanging(app, event)
            app.animateJointAngles;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutonomousModeTab = uitab(app.TabGroup);
            app.AutonomousModeTab.Title = 'Autonomous Mode';
            app.LashesButton = uibutton(app.AutonomousModeTab, 'push');
            app.LashesButton.ButtonPushedFcn = createCallbackFcn(app, @LashesButtonPushed, true);
            app.LashesButton.Text = '350 Lashes';
            app.ServeadeliciousbevvyLabel = uilabel(app.AutonomousModeTab);
            app.ServeadeliciousbevvyLabel.Text = 'Serve a delicious bevvy:';
            app.RooheysButton = uibutton(app.AutonomousModeTab, 'push');
            app.RooheysButton.Text = 'Rooheys';
            app.BigCreaturesPaleButton = uibutton(app.AutonomousModeTab, 'push');
            app.BigCreaturesPaleButton.Text = 'Big Creatures Pale';
            app.SupermoistButton = uibutton(app.AutonomousModeTab, 'push');
            app.SupermoistButton.Text = 'Supermoist';
            app.EStopPushedLampLabel = uilabel(app.AutonomousModeTab);
            app.EStopPushedLampLabel.Text = 'Task Completed';
            app.EStopPushedLamp = uilamp(app.AutonomousModeTab);
            app.AutonomousModeLabel = uilabel(app.AutonomousModeTab);
            app.AutonomousModeLabel.Text = 'Autonomous Mode';
            app.CollisionDetectedLampLabel = uilabel(app.AutonomousModeTab);
            app.CollisionDetectedLampLabel.Text = 'Collision Detected';
            app.CollisionDetectedLamp = uilamp(app.AutonomousModeTab);
            app.EStopPushedLamp_2Label = uilabel(app.AutonomousModeTab);
            app.EStopPushedLamp_2Label.Text = 'E-Stop Pushed';
            app.EStopPushedLamp_2 = uilamp(app.AutonomousModeTab);
            app.RunningLampLabel = uilabel(app.AutonomousModeTab);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.AutonomousModeTab);
            app.StatusLabel = uilabel(app.AutonomousModeTab);
            app.StatusLabel.Text = 'Status';
            app.UR3ControllerTab = uitab(app.TabGroup);
            app.UR3ControllerTab.Title = 'UR3 Controller';
            app.UR3ManualControlLabel = uilabel(app.UR3ControllerTab);
            app.UR3ManualControlLabel.Text = 'UR3 Manual Control';
            app.CartesianmovementGlobalLabel = uilabel(app.UR3ControllerTab);
            app.CartesianmovementGlobalLabel.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel = uilabel(app.UR3ControllerTab);
            app.JointmovementLabel.Text = 'Joint movement:';
            app.EndeffectorstepsizemKnobLabel = uilabel(app.UR3ControllerTab);
            app.EndeffectorstepsizemKnobLabel.Text = 'End effector step size (m)';
            app.ur3Speed = uiknob(app.UR3ControllerTab, 'continuous');
            app.ur3Speed.Limits = [0 0.1];
            app.ur3Speed.Value = 0.01;
            app.Joint1SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.ur3Joint1 = uislider(app.UR3ControllerTab);
            app.ur3Joint1.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.ur3Joint2 = uislider(app.UR3ControllerTab);
            app.ur3Joint2.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.ur3Joint3 = uislider(app.UR3ControllerTab);
            app.ur3Joint3.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.ur3Joint4 = uislider(app.UR3ControllerTab);
            app.ur3Joint4.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.ur3Joint5 = uislider(app.UR3ControllerTab);
            app.ur3Joint5.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.ur3Joint6 = uislider(app.UR3ControllerTab);
            app.ur3Joint6.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.XLabel = uilabel(app.UR3ControllerTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UR3ControllerTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UR3ControllerTab);
            app.ZLabel.Text = 'Z';
            app.ur3XPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3XPos.ValueChangedFcn = createCallbackFcn(app, @UPur3XPosPushed, true);
            app.ur3XPos.Text = '+';
            app.ur3XNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3XNeg.Text = '-';
            app.ur3YPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3YPos.Text = '+';
            app.ur3YNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3YNeg.Text = '-';
            app.ur3ZPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3ZPos.Text = '+';
            app.ur3ZNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3ZNeg.Text = '-';
            app.CR5ControllerTab = uitab(app.TabGroup);
            app.CR5ControllerTab.Title = 'CR5 Controller';
            app.CR5ManualControlLabel = uilabel(app.CR5ControllerTab);
            app.CR5ManualControlLabel.Text = 'CR5 Manual Control';
            app.CartesianmovementGlobalLabel_2 = uilabel(app.CR5ControllerTab);
            app.CartesianmovementGlobalLabel_2.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel_2 = uilabel(app.CR5ControllerTab);
            app.JointmovementLabel_2.Text = 'Joint movement:';
            app.XLabel_2 = uilabel(app.CR5ControllerTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.CR5ControllerTab);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.CR5ControllerTab);
            app.ZLabel_2.Text = 'Z';
            app.cr5XPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5XPos.Text = '+';
            app.cr5XNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5XNeg.Text = '-';
            app.cr5YPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5YPos.Text = '+';
            app.cr5YNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5YNeg.Text = '-';
            app.cr5ZPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5ZPos.Text = '+';
            app.cr5ZNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5ZNeg.Text = '-';
            app.EndeffectorstepsizemKnobLabel_2 = uilabel(app.CR5ControllerTab);
            app.EndeffectorstepsizemKnobLabel_2.Text = 'End effector step size (m)';
            app.cr5Speed = uiknob(app.CR5ControllerTab, 'continuous');
            app.cr5Speed.Limits = [0 0.1];
            app.cr5Speed.Value = 0.01;
            app.Joint1Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.cr5Joint1 = uislider(app.CR5ControllerTab);
            app.cr5Joint1.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint2Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.cr5Joint2 = uislider(app.CR5ControllerTab);
            app.cr5Joint2.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint3Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.cr5Joint3 = uislider(app.CR5ControllerTab);
            app.cr5Joint3.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint4Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.cr5Joint4 = uislider(app.CR5ControllerTab);
            app.cr5Joint4.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint5Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.cr5Joint5 = uislider(app.CR5ControllerTab);
            app.cr5Joint5.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.Joint6Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.cr5Joint6 = uislider(app.CR5ControllerTab);
            app.cr5Joint6.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.EStop = uibutton(app.UIFigure, 'state');
            app.EStop.ValueChangedFcn = createCallbackFcn(app, @EStopValueChanged, true);
            app.EStop.Icon = fullfile(pathToMLAPP, 'images', 'E-Stop.png');
            app.EStop.Text = '';
            app.BOOTBOOZEBOTButton = uibutton(app.UIFigure, 'push');
            app.BOOTBOOZEBOTButton.ButtonPushedFcn = createCallbackFcn(app, @BOOTBOOZEBOTButtonPushed, true);
            app.BOOTBOOZEBOTButton.Text = 'BOOT BOOZE BOT';
            app.estopWarning = uilabel(app.UIFigure);
            app.estopWarning.Enable = 'off';
            app.estopWarning.Visible = 'off';
            app.estopWarning.Text = 'E-STOP PRESSED, RELEASE E-STOP TO CONTROL ROBOTS OR KILL PROGRAM';
            app.ResumeProgrammeButton = uibutton(app.UIFigure, 'state');
            app.ResumeProgrammeButton.ValueChangedFcn = createCallbackFcn(app, @ResumeProgrammeButtonValueChanged, true);
            app.ResumeProgrammeButton.Enable = 'off';
            app.ResumeProgrammeButton.Text = 'Resume Programme';
            app.EndProgrammeButton = uibutton(app.UIFigure, 'state');
            app.EndProgrammeButton.ValueChangedFcn = createCallbackFcn(app, @EndProgrammeButtonValueChanged, true);
            app.EndProgrammeButton.Enable = 'off';
            app.EndProgrammeButton.Text = 'End Programme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end